classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        AnchoBandaEditField        matlab.ui.control.NumericEditField
        AnchoBandaEditFieldLabel   matlab.ui.control.Label
        EnergiaEditField           matlab.ui.control.NumericEditField
        EnergiaEditFieldLabel      matlab.ui.control.Label
        EficienciaEditField        matlab.ui.control.NumericEditField
        EficienciaEditFieldLabel   matlab.ui.control.Label
        PotenciaEditField          matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel     matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        SemillaFijaCheckBox        matlab.ui.control.CheckBox
        SimularButton              matlab.ui.control.Button
        EbN0EditField              matlab.ui.control.NumericEditField
        EbN0EditFieldLabel         matlab.ui.control.Label
        RollOffEditField           matlab.ui.control.NumericEditField
        RollOffEditFieldLabel      matlab.ui.control.Label
        OrdenMEditField            matlab.ui.control.NumericEditField
        OrdenMEditFieldLabel       matlab.ui.control.Label
        EsquemaDropDown            matlab.ui.control.DropDown
        EsquemaDropDownLabel       matlab.ui.control.Label
        UIAxesEspectro             matlab.ui.control.UIAxes
        UIAxesBER                  matlab.ui.control.UIAxes
        UIAxesConstelacion         matlab.ui.control.UIAxes
        UIAxesOjo                  matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
        CalcularComparativaButton  matlab.ui.control.Button
        ComparativaTable           matlab.ui.control.Table
        UIAxesComparativa          matlab.ui.control.UIAxes
    end

    
methods (Access = private)

    % 1. Validación de entradas (RNF1)
    function valido = validarParametros(app, M, alpha)
        valido = false;
        % Verificar factor de roll-off entre 0 y 1
        if alpha < 0 || alpha > 1 || isnan(alpha)
            uialert(app.UIFigure, ...
                'El factor de roll-off debe estar entre 0 y 1.', ...
                'Error de Parámetro');
            return;
        end
        % Verificar que M sea potencia de 2 y mayor o igual a 2
        k = log2(M);
        if M < 2 || mod(k, 1) ~= 0
            uialert(app.UIFigure, ...
                'El orden M debe ser una potencia de 2 (ej. 2, 4, 8, 16, 64).', ...
                'Error en Orden de Modulación');
            return;
        end
        % Para M-QAM cuadrada/rectangular validar M >= 4
        if strcmp(app.EsquemaDropDown.Value, 'M-QAM') && M < 4
            uialert(app.UIFigure, ...
                'Para M-QAM seleccione un orden M mayor o igual a 4 (ej. 4, 16, 64).', ...
                'Error en M-QAM');
            return;
        end
        valido = true;
    end

    % 2. Generación manual del diccionario de constelación y código Gray
    function [constelacion, grayMap] = generarConstelacion(~, tipo, M)
        idx = (0:M-1).';
        % Conversión binaria a código Gray mediante XOR bit a bit
        grayMap = bitxor(idx, bitshift(idx, -1));
        
        switch tipo
            case 'M-PAM'
                % Niveles simétricos: -(M-1), ..., +(M-1)
                niveles = (-(M-1):2:(M-1)).';
                constelacion = zeros(M, 1);
                constelacion(grayMap + 1) = niveles;
                
            case 'M-PSK'
                % Fases uniformemente distribuidas en el círculo unitario
                fases = 2 * pi * idx / M;
                 validatePhase = exp(1j * fases);
                constelacion = zeros(M, 1);
                constelacion(grayMap + 1) = validatePhase;
                
            case 'M-QAM'
                k = log2(M);
                if mod(k, 2) == 0
                    L = sqrt(M);
                    Lx = L; Ly = L;
                else
                    Lx = 2^((k+1)/2);
                    Ly = 2^((k-1)/2);
                end
                nivelesI = (-(Lx-1):2:(Lx-1));
                nivelesQ = (-(Ly-1):2:(Ly-1));
                grayI = bitxor(0:Lx-1, bitshift(0:Lx-1, -1));
                grayQ = bitxor(0:Ly-1, bitshift(0:Ly-1, -1));
                
                constelacion = zeros(M, 1);
                for i = 1:Lx
                    for q = 1:Ly
                        symIdx = grayI(i) * Ly + grayQ(q);
                        constelacion(symIdx + 1) = nivelesI(i) + 1j * nivelesQ(q);
                    end
                end
        end
    end

    % 3. Filtro Raíz de Coseno Alzado (SRRC) desde su ecuación matemática
    function h = filtroSRRC(~, alpha, sps, span)
        t = (-span/2 : 1/sps : span/2).';
        h = zeros(size(t));
        for n = 1:length(t)
            ti = t(n);
            if ti == 0
                h(n) = 1 - alpha + (4 * alpha / pi);
            elseif alpha ~= 0 && abs(abs(ti) - 1/(4*alpha)) < 1e-9
                h(n) = (alpha / sqrt(2)) * ...
                    ((1 + 2/pi)*sin(pi/(4*alpha)) + (1 - 2/pi)*cos(pi/(4*alpha)));
            else
                num = sin(pi * ti * (1 - alpha)) + ...
                      4 * alpha * ti * cos(pi * ti * (1 + alpha));
                den = pi * ti * (1 - (4 * alpha * ti)^2);
                h(n) = num / den;
            end
        end
        % Normalizar energía del pulso a 1
        h = h / sqrt(sum(h.^2));
    end

    % 4. Decisor por distancia euclidiana mínima (Bajo nivel)
    function [idxDecididos, symDecididos] = decisorMinDistancia(~, rxSym, constelacion)
        N = length(rxSym);
        idxDecididos = zeros(N, 1);
        symDecididos = zeros(N, 1);
        for n = 1:N
            distancias = abs(rxSym(n) - constelacion).^2;
            [~, minIdx] = min(distancias);
            idxDecididos(n) = minIdx - 1; % Índice entre 0 y M-1
            symDecididos(n) = constelacion(minIdx);
        end
    end
    function cargarTablaComparativaUI(app)
        % 1. Niveles de BER solicitados en las condiciones de entrega
        ber_targets = [1e-2; 1e-3; 1e-4; 1e-5; 1e-6; 1e-7];
        N = length(ber_targets);

        EbN0_QAM_dB = zeros(N, 1);
        EbN0_PAM_dB = zeros(N, 1);
        EbN0_PSK_dB = zeros(N, 1);

        % 2. Despeje analítico de Eb/N0 usando la función inversa erfc (RNF2)
        % Q(x) = 0.5 * erfc(x / sqrt(2))  ==>  x = sqrt(2) * erfcinv(2 * Q)
        for i = 1:N
            Pb = ber_targets(i);

            % Para 4-QAM y 4-PSK: Pb = Q(sqrt(2 * Eb/N0))
            x_qam = sqrt(2) * erfcinv(2 * Pb);
            EbN0_lin_qam = (x_qam^2) / 2;
            EbN0_QAM_dB(i) = 10 * log10(EbN0_lin_qam);
            EbN0_PSK_dB(i) = EbN0_QAM_dB(i); % Idénticas geométricamente

            % Para 4-PAM: Pb ≈ (3/4) * Q(sqrt(0.8 * Eb/N0))
            x_pam = sqrt(2) * erfcinv(2 * (4/3 * Pb));
            EbN0_lin_pam = (x_pam^2) / 0.8;
            EbN0_PAM_dB(i) = 10 * log10(EbN0_lin_pam);
        end

        % 3. Cálculo de las diferencias de potencia solicitadas
        dif_PAM_QAM = EbN0_PAM_dB - EbN0_QAM_dB;
        dif_PSK_QAM = EbN0_PSK_dB - EbN0_QAM_dB;
        dif_QAM_PAM = EbN0_QAM_dB - EbN0_PAM_dB;

        % 4. Llenado y formateo de la UITable en la interfaz gráfica
        etiquetasBER = {'10^-2'; '10^-3'; '10^-4'; '10^-5'; '10^-6'; '10^-7'};
        datosCeldas = cell(N, 4);

        for i = 1:N
            datosCeldas{i, 1} = etiquetasBER{i};
            datosCeldas{i, 2} = sprintf('+%.2f dB', dif_PAM_QAM(i));
            datosCeldas{i, 3} = sprintf(' %.2f dB', dif_PSK_QAM(i));
            datosCeldas{i, 4} = sprintf('%.2f dB', dif_QAM_PAM(i));
        end

        % Asignación de encabezados y matriz de datos al UITable
        app.ComparativaTable.ColumnName = {'BER', '4PAM-4QAM', '4PSK-4QAM', '4QAM-4PAM'};
        app.ComparativaTable.RowName = {};       % Ocultar numeración lateral
        app.ComparativaTable.Data = datosCeldas;

        % 5. Graficación de las curvas de análisis en el UIAxes de la pestaña
        cla(app.UIAxesComparativa);
        EbN0_eje = 0:0.1:16;
        gamma_lin = 10.^(EbN0_eje / 10);

        curva_QAM = 0.5 * erfc(sqrt(gamma_lin));
        curva_PAM = (3/4) * 0.5 * erfc(sqrt(0.4 * gamma_lin));

        semilogy(app.UIAxesComparativa, EbN0_eje, curva_PAM, 'r-', 'LineWidth', 2, 'DisplayName', '4-PAM');
        hold(app.UIAxesComparativa, 'on');
        semilogy(app.UIAxesComparativa, EbN0_eje, curva_QAM, 'b--', 'LineWidth', 2, 'DisplayName', '4-QAM / 4-PSK');

        % Marcadores puntuales correspondientes a las filas de la tabla
        plot(app.UIAxesComparativa, EbN0_PAM_dB, ber_targets, 'ro', 'MarkerFaceColor', 'r', 'DisplayName', 'Puntos evaluados 4-PAM');
        plot(app.UIAxesComparativa, EbN0_QAM_dB, ber_targets, 'bs', 'MarkerFaceColor', 'b', 'DisplayName', 'Puntos evaluados 4-QAM/PSK');
        hold(app.UIAxesComparativa, 'off');

        grid(app.UIAxesComparativa, 'on');
        ylim(app.UIAxesComparativa, [1e-8 1]);
        xlim(app.UIAxesComparativa, [0 16]);
        title(app.UIAxesComparativa, 'Curvas Comparativas: 4-PAM vs 4-QAM / 4-PSK');
        xlabel(app.UIAxesComparativa, 'E_b / N_0 (dB)');
        ylabel(app.UIAxesComparativa, 'Tasa de Error de Bit (BER)');
        legend(app.UIAxesComparativa, 'Location', 'northeast');
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
% 1. Lectura de parámetros de la interfaz (RF1, RF2)
    tipo = app.EsquemaDropDown.Value;
    M = round(app.OrdenMEditField.Value);
    alpha = app.RollOffEditField.Value;
    EbN0_dB_op = app.EbN0EditField.Value;

    % Validación de parámetros de entrada (RNF1)
    if ~app.validarParametros(M, alpha)
        return;
    end

    % Semilla para reproducibilidad (RNF3)
    if app.SemillaFijaCheckBox.Value
        rng(42);
    end

    % Parámetros base del sistema de comunicación
    k = log2(M);
    Nsym = 3000;          % Número de símbolos transmitidos
    Rs = 1000;            % Tasa de símbolos (1 kBaud)
    sps = 16;             % Factor de sobremuestreo
    fs = Rs * sps;        % Frecuencia de muestreo (16 kHz)
    fc = 4000;            % Frecuencia de portadora pasabanda
    span = 8;             % Duración temporal del pulso SRRC en símbolos

    % --- 2. MAPEO Y CONSTELACIÓN CON CÓDIGO GRAY (RNF2) ---
    Nbits = Nsym * k;
    bitsTx = randi([0 1], Nbits, 1);
    bitsMat = reshape(bitsTx, k, Nsym).';
    symIdxTx = bitsMat * (2.^(k-1:-1:0)).';

    [constelacion, ~] = app.generarConstelacion(tipo, M);
    symTx = constelacion(symIdxTx + 1);

    % --- 3. CONFORMADOR DE PULSO SRRC (BANDA BASE) ---
    h = app.filtroSRRC(alpha, sps, span);
    upsampled = zeros(Nsym * sps, 1);
    upsampled(1:sps:end) = symTx;
    % Convolución con 'same' mantiene longitud = 48000
    bbTx = conv(upsampled, h, 'same');

    % --- 4. MODULACIÓN ANALÓGICA PASABANDA ---
    t = (0:length(bbTx)-1).' / fs;
    portadoraI = sqrt(2) * cos(2 * pi * fc * t);
    portadoraQ = -sqrt(2) * sin(2 * pi * fc * t);

    if strcmp(tipo, 'M-PAM')
        % Modulación unidimensional (solo en fase)
        sTx = real(bbTx) .* portadoraI;
    else
        % Modulación bidimensional (I y Q)
        sTx = real(bbTx) .* portadoraI + imag(bbTx) .* portadoraQ;
    end

    % --- 5. CANAL AWGN (CORRECCIÓN DE POTENCIA DE RUIDO) ---
    Es = mean(abs(constelacion).^2);
    Eb = Es / k;
    EbN0_lin = 10^(EbN0_dB_op / 10);
    N0 = Eb / EbN0_lin;

    % Varianza para ruido gaussiano en canal pasabanda
    sigma = sqrt(N0 / 2);
    ruido = sigma * randn(size(sTx));
    sRx = sTx + ruido;

    % --- 6. DEMODULACIÓN COHERENTE Y FILTRO ACOPLADO ---
    demodI = sRx .* portadoraI;
    if strcmp(tipo, 'M-PAM')
        bbRx_cruda = demodI;
    else
        demodQ = sRx .* portadoraQ;
        bbRx_cruda = demodI + 1j * demodQ;
    end

    % Filtro acoplado en recepción con 'same' (longitud idéntica = 48000)
    bbRx = conv(bbRx_cruda, h, 'same');

    % Muestreo óptimo sin error de dimensiones (exactamente 3000 muestras)
    rxSym = bbRx(1:sps:end);
    if strcmp(tipo, 'M-PAM')
        rxSym = real(rxSym);
    end

    % Decisor por mínima distancia euclidiana
    [rxDecididos, ~] = app.decisorMinDistancia(rxSym, constelacion);

    % --- 7. CÁLCULO DE LAS 4 MÉTRICAS (RF4) ---
    if strcmp(tipo, 'M-PAM')
        BW = (Rs / 2) * (1 + alpha);      % Ancho de banda en banda base
    else
        BW = Rs * (1 + alpha);            % Ancho de banda pasabanda
    end
    Rb = Rs * k;
    eficiencia = Rb / BW;
    potenciaMod = mean(sTx.^2);

    % Asignación directa de escalares numéricos (double)
    app.EficienciaEditField.Value = eficiencia;
    app.EnergiaEditField.Value    = Es;
    app.AnchoBandaEditField.Value = BW;
    app.PotenciaEditField.Value   = potenciaMod;
    % --- 8. ACTUALIZACIÓN DE LAS 4 GRÁFICAS (RF3) ---

    % 8.1 Constelación en Recepción
    cla(app.UIAxesConstelacion);
    if strcmp(tipo, 'M-PAM')
        plot(app.UIAxesConstelacion, real(rxSym), zeros(size(rxSym)), '.', ...
            'Color', [0.2 0.6 0.8], 'MarkerSize', 6);
        hold(app.UIAxesConstelacion, 'on');
        plot(app.UIAxesConstelacion, real(constelacion), zeros(size(constelacion)), ...
            'ro', 'MarkerSize', 8, 'LineWidth', 2);
        ylim(app.UIAxesConstelacion, [-1 1]);
    else
        plot(app.UIAxesConstelacion, real(rxSym), imag(rxSym), '.', ...
            'Color', [0.2 0.6 0.8], 'MarkerSize', 6);
        hold(app.UIAxesConstelacion, 'on');
        plot(app.UIAxesConstelacion, real(constelacion), imag(constelacion), ...
            'ro', 'MarkerSize', 8, 'LineWidth', 2);
    end
    hold(app.UIAxesConstelacion, 'off');
    grid(app.UIAxesConstelacion, 'on');
    title(app.UIAxesConstelacion, sprintf('Constelación en Recepción (%d-%s)', M, tipo(3:end)));
    xlabel(app.UIAxesConstelacion, 'En Fase (I)');
    ylabel(app.UIAxesConstelacion, 'Cuadratura (Q)');

    % 8.2 Diagrama del Ojo en Recepción (Apertura Nítida)
    cla(app.UIAxesOjo);
    muestrasOjo = 2 * sps; % Ventana de 2 símbolos (32 muestras)
    % Se descartan los transitorios de los bordes del filtro SRRC
    inicioUtil = (span / 2) * sps + 1;
    finUtil = length(bbRx) - (span / 2) * sps;
    senialOjo = real(bbRx(inicioUtil : finUtil));
    
    numTrazos = min(120, floor(length(senialOjo) / muestrasOjo));
    matrizOjo = reshape(senialOjo(1 : numTrazos * muestrasOjo), muestrasOjo, numTrazos);
    tOjo = (0 : muestrasOjo - 1) / sps;
    plot(app.UIAxesOjo, tOjo, matrizOjo, 'Color', [0 0.45 0.74 0.35]);
    grid(app.UIAxesOjo, 'on');
    title(app.UIAxesOjo, 'Diagrama del Ojo en Recepción (Rama I)');
    xlabel(app.UIAxesOjo, 'Tiempo (t/Ts)');
    ylabel(app.UIAxesOjo, 'Amplitud');

    % 8.3 Espectros Tx (Banda Base) vs Rx (Pasa Banda)
    cla(app.UIAxesEspectro);
    Nfft = 2^nextpow2(length(sTx));
    f = (-Nfft/2 : Nfft/2 - 1) * (fs / Nfft);
    SpecBB = abs(fftshift(fft(bbTx, Nfft))) / length(bbTx);
    SpecPB = abs(fftshift(fft(sRx, Nfft))) / length(sRx);
    plot(app.UIAxesEspectro, f, 20*log10(SpecBB + 1e-12), 'b', 'LineWidth', 1.2, 'DisplayName', 'Banda Base (Tx)');
    hold(app.UIAxesEspectro, 'on');
    plot(app.UIAxesEspectro, f, 20*log10(SpecPB + 1e-12), 'r', 'LineWidth', 1.2, 'DisplayName', 'Pasa Banda (Rx)');
    hold(app.UIAxesEspectro, 'off');
    grid(app.UIAxesEspectro, 'on');
    xlim(app.UIAxesEspectro, [-fs/2 fs/2]);
    ylim(app.UIAxesEspectro, [-80 10]);
    legend(app.UIAxesEspectro, 'Location', 'northeast');
    title(app.UIAxesEspectro, 'Espectro de la Señal (Tx vs Rx)');
    xlabel(app.UIAxesEspectro, 'Frecuencia (Hz)');
    ylabel(app.UIAxesEspectro, 'Magnitud (dB)');

    % 8.4 Curva BER: Simulación vs Curva Teórica Analítica (RF3 y Obj. 1)
    cla(app.UIAxesBER);
    EbN0_vec = 0:2:14;
    berSim = zeros(size(EbN0_vec));
    for b = 1:length(EbN0_vec)
        ebn0_lin = 10^(EbN0_vec(b) / 10);
        n0_b = Eb / ebn0_lin;
        if strcmp(tipo, 'M-PAM')
            ruidoB = sqrt(n0_b / 2) * randn(size(symTx));
        else
            ruidoB = sqrt(n0_b / 2) * (randn(size(symTx)) + 1j*randn(size(symTx)));
        end
        rx_b = symTx + ruidoB;
        [idx_b, ~] = app.decisorMinDistancia(rx_b, constelacion);
        
        erroresBits = 0;
        for n = 1:Nsym
            erroresBits = erroresBits + sum(bitget(bitxor(symIdxTx(n), idx_b(n)), 1:k));
        end
        berSim(b) = erroresBits / Nbits;
    end
    berSim(berSim == 0) = 1e-6;

    % Cálculo analítico de BER teórico
    EbN0_fine = 0:0.2:14;
    gamma_lin = 10.^(EbN0_fine / 10);
    switch tipo
        case 'M-PAM'
            berTeorica = (2*(M-1)/(M*k)) * 0.5 * erfc(sqrt((6*k/(M^2-1)) * gamma_lin) / sqrt(2));
        case 'M-PSK'
            berTeorica = (2/k) * 0.5 * erfc(sqrt(2*k*gamma_lin) * sin(pi/M) / sqrt(2));
        case 'M-QAM'
            berTeorica = (4/k)*(1 - 1/sqrt(M)) * 0.5 * erfc(sqrt((3*k/(M-1)) * gamma_lin) / sqrt(2));
    end

    semilogy(app.UIAxesBER, EbN0_fine, berTeorica, 'k--', 'LineWidth', 1.5, 'DisplayName', 'Teórica');
    hold(app.UIAxesBER, 'on');
    semilogy(app.UIAxesBER, EbN0_vec, berSim, 'bo', 'MarkerFaceColor', 'b', 'DisplayName', 'Simulada');
    hold(app.UIAxesBER, 'off');
    grid(app.UIAxesBER, 'on');
    ylim(app.UIAxesBER, [1e-6 1]);
    xlim(app.UIAxesBER, [0 14]);
    title(app.UIAxesBER, sprintf('BER vs Eb/N0 (%d-%s)', M, tipo(3:end)));
    xlabel(app.UIAxesBER, 'Eb/N0 (dB)');
    ylabel(app.UIAxesBER, 'BER');
    legend(app.UIAxesBER, 'Location', 'southwest');
        end

        % Button pushed function: CalcularComparativaButton
        function CalcularComparativaButtonPushed(app, event)
            app.cargarTablaComparativaUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxesOjo = uiaxes(app.Tab);
            title(app.UIAxesOjo, 'Title')
            xlabel(app.UIAxesOjo, 'X')
            ylabel(app.UIAxesOjo, 'Y')
            zlabel(app.UIAxesOjo, 'Z')
            app.UIAxesConstelacion = uiaxes(app.Tab);
            title(app.UIAxesConstelacion, 'Title')
            xlabel(app.UIAxesConstelacion, 'X')
            ylabel(app.UIAxesConstelacion, 'Y')
            zlabel(app.UIAxesConstelacion, 'Z')
            app.UIAxesBER = uiaxes(app.Tab);
            title(app.UIAxesBER, 'Title')
            xlabel(app.UIAxesBER, 'X')
            ylabel(app.UIAxesBER, 'Y')
            zlabel(app.UIAxesBER, 'Z')
            app.UIAxesEspectro = uiaxes(app.Tab);
            title(app.UIAxesEspectro, 'Title')
            xlabel(app.UIAxesEspectro, 'X')
            ylabel(app.UIAxesEspectro, 'Y')
            zlabel(app.UIAxesEspectro, 'Z')
            app.EsquemaDropDownLabel = uilabel(app.Tab);
            app.EsquemaDropDownLabel.Text = 'Modulacion';
            app.EsquemaDropDown = uidropdown(app.Tab);
            app.EsquemaDropDown.Items = {'M-PAM', 'M-PSK', 'M-QAM'};
            app.EsquemaDropDown.Value = 'M-PAM';
            app.OrdenMEditFieldLabel = uilabel(app.Tab);
            app.OrdenMEditFieldLabel.Text = 'Orden M';
            app.OrdenMEditField = uieditfield(app.Tab, 'numeric');
            app.OrdenMEditField.Value = 4;
            app.RollOffEditFieldLabel = uilabel(app.Tab);
            app.RollOffEditFieldLabel.Text = '	RollOff';
            app.RollOffEditField = uieditfield(app.Tab, 'numeric');
            app.RollOffEditField.Value = 0.35;
            app.EbN0EditFieldLabel = uilabel(app.Tab);
            app.EbN0EditFieldLabel.Text = 'EbN0';
            app.EbN0EditField = uieditfield(app.Tab, 'numeric');
            app.EbN0EditField.Value = 10;
            app.SimularButton = uibutton(app.Tab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.SemillaFijaCheckBox = uicheckbox(app.Tab);
            app.SemillaFijaCheckBox.Text = 'Semilla';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '	SIMULADOR DE COMUNICACIONES DIGITALES';
            app.PotenciaEditFieldLabel = uilabel(app.Tab);
            app.PotenciaEditFieldLabel.Text = 'Potencia Modulada';
            app.PotenciaEditField = uieditfield(app.Tab, 'numeric');
            app.EficienciaEditFieldLabel = uilabel(app.Tab);
            app.EficienciaEditFieldLabel.Text = 'Eficiencia';
            app.EficienciaEditField = uieditfield(app.Tab, 'numeric');
            app.EnergiaEditFieldLabel = uilabel(app.Tab);
            app.EnergiaEditFieldLabel.Text = 'Energia de Simbolo';
            app.EnergiaEditField = uieditfield(app.Tab, 'numeric');
            app.AnchoBandaEditFieldLabel = uilabel(app.Tab);
            app.AnchoBandaEditFieldLabel.Text = 'Ancho de Banda';
            app.AnchoBandaEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxesComparativa = uiaxes(app.Tab2);
            title(app.UIAxesComparativa, 'Title')
            xlabel(app.UIAxesComparativa, 'X')
            ylabel(app.UIAxesComparativa, 'Y')
            zlabel(app.UIAxesComparativa, 'Z')
            app.ComparativaTable = uitable(app.Tab2);
            app.ComparativaTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ComparativaTable.RowName = {};
            app.CalcularComparativaButton = uibutton(app.Tab2, 'push');
            app.CalcularComparativaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularComparativaButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end